classdef DialogPreferences < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        btnSave                 matlab.ui.control.Button
        SharedfunctionsPanel    matlab.ui.container.Panel
        FolderslocationLabel    matlab.ui.control.Label
        btnSharedFolderSelect   matlab.ui.control.Button
        txtSharedFolder         matlab.ui.control.EditField
        cmbProcessors           matlab.ui.control.DropDown
        EpochprocessorsLabel    matlab.ui.control.Label
        cmbExtractors           matlab.ui.control.DropDown
        FeatureextractorsLabel  matlab.ui.control.Label
        btnSettingsProcessor    matlab.ui.control.Button
        btnSettingsExtractor    matlab.ui.control.Button
    end

    
    properties (Access = private)
        Parent              % Application calling this dialog
    end
    
    properties (Access = public)
        Settings % Description
    end
    
    methods (Access = public)        
        function setParameters(app, params)
            app.params = params;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent, settings)
            app.Parent = parent;
            if ~isempty(settings)
                app.Settings = settings;
                if isfield(app.Settings, 'SharedFolder')
                    app.txtSharedFolder.Value = app.Settings.SharedFolder;
                    app.txtSharedFolderValueChanged;
                end
            end
        end

        % Button pushed function: btnSave
        function btnSaveButtonPushed(app, event)
            % Update settings with current ones
            app.Settings.SharedFolder = app.txtSharedFolder.Value;
            
            % Ask Clarinet to save them on disk
            app.Parent.saveSettings(app.Settings);
            app.UIFigure.Visible = 'off';
        end

        % Button pushed function: btnSharedFolderSelect
        function btnSharedFolderSelectButtonPushed(app, event)
                    selPath = uigetdir(app.txtSharedFolder.Value,'Select a new folder');

                    if exist(selPath,'dir')
                        app.txtSharedFolder.Value = selPath;
                        app.txtSharedFolderValueChanged;
                    end
                    app.UIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
                    app.UIFigure.Visible = 'on';  %Fixes glitch by which app loses focus            
        end

        % Value changed function: txtSharedFolder
        function txtSharedFolderValueChanged(app, event)
            selPath = app.txtSharedFolder.Value;
            if exist(selPath,'dir')
                addpath(selPath); % Add the new path to matlab
                
                import sharedProcessors.*
                if ~isempty(meta.package.fromName('sharedProcessors'))
                    customFilters = meta.package.fromName('sharedProcessors').FunctionList;
                    customFilterNames = {customFilters.Name};
                    customFilterNames = sort(customFilterNames);
                    app.cmbProcessors.Items = customFilterNames;
                else
                    app.cmbProcessors.Items = {};
                end
                
                import sharedExtractors.*
                if ~isempty(meta.package.fromName('sharedExtractors'))
                    customFilters = meta.package.fromName('sharedExtractors').FunctionList;
                    customFilterNames = {customFilters.Name};
                    customFilterNames = sort(customFilterNames);
                    app.cmbExtractors.Items = customFilterNames;
                else
                    app.cmbExtractors.Items = {};
                end
            end
            
        end

        % Button pushed function: btnSettingsProcessor
        function btnSettingsProcessorButtonPushed(app, event)
            import sharedProcessors.*            
            filterName = app.cmbProcessors.Value;
            if ~isempty(filterName)
                settings = feval(filterName);
                editStruct(settings);
            end
        end

        % Button pushed function: btnSettingsExtractor
        function btnSettingsExtractorButtonPushed(app, event)
            import sharedExtractors.*            
            filterName = app.cmbExtractors.Value;
            if ~isempty(filterName)
                settings = feval(filterName);
                editStruct(settings);  
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Preferences';
            app.btnSave = uibutton(app.UIFigure, 'push');
            app.btnSave.ButtonPushedFcn = createCallbackFcn(app, @btnSaveButtonPushed, true);
            app.btnSave.Tooltip = {'Save current settings to disk'};
            app.btnSave.Text = 'Save';
            app.SharedfunctionsPanel = uipanel(app.UIFigure);
            app.SharedfunctionsPanel.Tooltip = {'Define the folder location of your shared functions (e.g. Dropbox / remote drive)'};
            app.SharedfunctionsPanel.Title = 'Shared functions';
            app.FolderslocationLabel = uilabel(app.SharedfunctionsPanel);
            app.FolderslocationLabel.Text = 'Folders location:';
            app.btnSharedFolderSelect = uibutton(app.SharedfunctionsPanel, 'push');
            app.btnSharedFolderSelect.ButtonPushedFcn = createCallbackFcn(app, @btnSharedFolderSelectButtonPushed, true);
            app.btnSharedFolderSelect.Icon = '48px-dots.png';
            app.btnSharedFolderSelect.Tooltip = {'Select a different folder'};
            app.btnSharedFolderSelect.Text = '';
            app.txtSharedFolder = uieditfield(app.SharedfunctionsPanel, 'text');
            app.txtSharedFolder.ValueChangedFcn = createCallbackFcn(app, @txtSharedFolderValueChanged, true);
            app.txtSharedFolder.Editable = 'off';
            app.txtSharedFolder.Tooltip = {'Folder location of your shared functions (e.g. Dropbox / Google drive / remote drive)'};
            app.cmbProcessors = uidropdown(app.SharedfunctionsPanel);
            app.cmbProcessors.Items = {};
            app.cmbProcessors.Tooltip = {'Epoch processors detected in the subfolder "+sharedProcessors"'};
            app.cmbProcessors.Value = {};
            app.EpochprocessorsLabel = uilabel(app.SharedfunctionsPanel);
            app.EpochprocessorsLabel.Text = 'Epoch processors:';
            app.cmbExtractors = uidropdown(app.SharedfunctionsPanel);
            app.cmbExtractors.Items = {};
            app.cmbExtractors.Tooltip = {'Feature extractors detected in the subfolder "+sharedExtractors"'};
            app.cmbExtractors.Value = {};
            app.FeatureextractorsLabel = uilabel(app.SharedfunctionsPanel);
            app.FeatureextractorsLabel.Text = 'Feature extractors:';
            app.btnSettingsProcessor = uibutton(app.SharedfunctionsPanel, 'push');
            app.btnSettingsProcessor.ButtonPushedFcn = createCallbackFcn(app, @btnSettingsProcessorButtonPushed, true);
            app.btnSettingsProcessor.Icon = '48px-settings.png';
            app.btnSettingsProcessor.Tooltip = {'Show default settings'};
            app.btnSettingsProcessor.Text = '';
            app.btnSettingsExtractor = uibutton(app.SharedfunctionsPanel, 'push');
            app.btnSettingsExtractor.ButtonPushedFcn = createCallbackFcn(app, @btnSettingsExtractorButtonPushed, true);
            app.btnSettingsExtractor.Icon = '48px-settings.png';
            app.btnSettingsExtractor.Tooltip = {'Show default settings'; ''};
            app.btnSettingsExtractor.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogPreferences(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end