classdef DialogSplitBy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        btnOK              matlab.ui.control.Button
        btnAdd             matlab.ui.control.Button
        btnRemove          matlab.ui.control.Button
        btnClear           matlab.ui.control.Button
        cmbTags            matlab.ui.control.DropDown
        TagLabel           matlab.ui.control.Label
        UITree             matlab.ui.container.Tree
        btnSave            matlab.ui.control.Button
        btnLoad            matlab.ui.control.Button
        btnClearSelection  matlab.ui.control.Button
    end

    
    properties (Access = private)
        params              % Description
        selectedRows = [];  % Row selected on the table
        SplitTree = tree('displayName');   % Tree representation
        Parent              % Application calling this dialog
        rootNode            % Description
    end
    
    properties (Access = public)
        Conditions % Description
    end
    
    methods (Access = public)
        
        function setParameters(app, params)
            app.params = params;
        end
    end
    
    methods (Access = private)
        
        function refreshUITree(app)            
            % Refreshes the UITree component from current app.CellData or app.UITree
            app.UITree.clo; % Clear previous UI tree
            app.rootNode = [];
            if app.SplitTree.nnodes == 0
                return
            end
            
            dt = app.SplitTree.depthtree;
            depth = 1;
            parentNodes = [];
            newParentNodes = [];
            
            for nodeIdx = app.SplitTree.breadthfirstiterator
                data = app.SplitTree.Node(nodeIdx);
                data = data{1};
                newDepth = dt.Node(nodeIdx);
                newDepth = newDepth{1};

                if isempty(app.rootNode)
                    node = uitreenode(app.UITree,'Text', data, 'NodeData',nodeIdx);
                    app.rootNode = node;
                    newParentNodes = node;
                elseif newDepth == depth
                    % Adding a new node within same depth order
                    parentIdx = app.SplitTree.getparent(nodeIdx);
                    parentNode = parentNodes([parentNodes.NodeData]== parentIdx);
                    node = uitreenode(parentNode,'Text', data,'NodeData', nodeIdx, 'Icon', '');
                    newParentNodes(end+1) = node;
                else
                    % Adding a new node in the next depth order
                    parentNodes = newParentNodes;
                    parentIdx = app.SplitTree.getparent(nodeIdx);
                    parentNode = parentNodes([parentNodes.NodeData]== parentIdx);
                    node = uitreenode(parentNode, 'Text', data,'NodeData', nodeIdx, 'Icon', '');
                    newParentNodes = node;
                end
                
                depth = newDepth;
            end                      
            
            app.btnRemove.Enable = 'off';
            app.btnClear.Enable  = 'on';
            app.UITree.expand('all');            
        end
        
        function populateUITree(app)
            app.UITree.clo; % Clear previous UI tree
            app.SplitTree = tree('displayName');
            app.rootNode = uitreenode(app.UITree,'Text', 'displayName', 'NodeData',1);
            app.UITree.SelectedNodes = app.rootNode;
            app.btnRemove.Enable = 'on';
            app.btnClear.Enable  = 'on';
            app.UITree.expand('all');            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent)
            app.Parent = parent;
            app.populateUITree;
        end

        % Button pushed function: btnClear
        function btnClearButtonPushed(app, event)
            app.populateUITree;
        end

        % Button pushed function: btnAdd
        function btnAddButtonPushed(app, event)
            newSplit = app.cmbTags.Value;
            selectedNodes = app.UITree.SelectedNodes;
            if isempty(selectedNodes)
                app.SplitTree = app.SplitTree.addnode(0, newSplit);
            else
                for idx = [selectedNodes.NodeData]
                    app.SplitTree = app.SplitTree.addnode(idx, newSplit);
                end
            end
            app.refreshUITree;
            app.UITree.SelectedNodes = app.rootNode;
        end

        % Button pushed function: btnRemove
        function btnRemoveButtonPushed(app, event)
           nodeIdx = [app.UITree.SelectedNodes.NodeData];
           app.SplitTree = app.SplitTree.chop(nodeIdx);
           if app.SplitTree.nnodes == 0
               app.btnRemove.Enable = 'off';
           end
           
           app.refreshUITree;      
        end

        % Button pushed function: btnOK
        function btnOKButtonPushed(app, event)
            app.Parent.splitEpochsByTags(app.SplitTree);
            app.UIFigure.Visible = 'off';
        end

        % Button pushed function: btnLoad
        function btnLoadButtonPushed(app, event)
            [fName, PathName] = uigetfile({'*.mat','Clarinet split strategy (*.mat)'}, 'Select file', 'MultiSelect', 'off');
            app.UIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
            app.UIFigure.Visible = 'on';  %Fixes glitch by which app loses focus
            
            data = load(fullfile(PathName, fName), 'SplitTree');
            app.SplitTree = data.SplitTree;
            app.refreshUITree;            
        end

        % Button pushed function: btnSave
        function btnSaveButtonPushed(app, event)
            % Save processing pipeline on disk
            SplitTree = app.SplitTree; %#ok
            uisave({'SplitTree'},'SplitTree.mat');
            
            app.UIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
            app.UIFigure.Visible = 'on';  %Fixes glitch by which app loses focus            
        end

        % Selection changed function: UITree
        function UITreeSelectionChanged(app, event)
            selectedNodes = app.UITree.SelectedNodes;
            if ~isempty(selectedNodes)
                app.btnRemove.Enable = 'on';
            else
                app.btnRemove.Enable = 'off';
            end
        end

        % Button pushed function: btnClearSelection
        function btnClearSelectionButtonPushed(app, event)
            app.UITree.SelectedNodes = [];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Split by';
            app.btnOK = uibutton(app.UIFigure, 'push');
            app.btnOK.ButtonPushedFcn = createCallbackFcn(app, @btnOKButtonPushed, true);
            app.btnOK.Tooltip = {'Search epochs meeting specified critera'};
            app.btnOK.Text = 'OK';
            app.btnAdd = uibutton(app.UIFigure, 'push');
            app.btnAdd.ButtonPushedFcn = createCallbackFcn(app, @btnAddButtonPushed, true);
            app.btnAdd.Icon = '48px-List-add.png';
            app.btnAdd.Tooltip = {'Add a new condition'};
            app.btnAdd.Text = '';
            app.btnRemove = uibutton(app.UIFigure, 'push');
            app.btnRemove.ButtonPushedFcn = createCallbackFcn(app, @btnRemoveButtonPushed, true);
            app.btnRemove.Icon = '48px-List-remove.png';
            app.btnRemove.Tooltip = {'Remove selected condition'};
            app.btnRemove.Text = '';
            app.btnClear = uibutton(app.UIFigure, 'push');
            app.btnClear.ButtonPushedFcn = createCallbackFcn(app, @btnClearButtonPushed, true);
            app.btnClear.Icon = '48px-list-clear.png';
            app.btnClear.Tooltip = {'Clear all conditions'};
            app.btnClear.Text = '';
            app.cmbTags = uidropdown(app.UIFigure);
            app.cmbTags.Items = {};
            app.cmbTags.Value = {};
            app.TagLabel = uilabel(app.UIFigure);
            app.TagLabel.Text = 'Tag:';
            app.UITree = uitree(app.UIFigure);
            app.UITree.SelectionChangedFcn = createCallbackFcn(app, @UITreeSelectionChanged, true);
            app.btnSave = uibutton(app.UIFigure, 'push');
            app.btnSave.ButtonPushedFcn = createCallbackFcn(app, @btnSaveButtonPushed, true);
            app.btnSave.Icon = '48px-save.png';
            app.btnSave.Tooltip = {'Remove selected condition'};
            app.btnSave.Text = '';
            app.btnLoad = uibutton(app.UIFigure, 'push');
            app.btnLoad.ButtonPushedFcn = createCallbackFcn(app, @btnLoadButtonPushed, true);
            app.btnLoad.Icon = '48px-open.png';
            app.btnLoad.Tooltip = {'Remove selected condition'};
            app.btnLoad.Text = '';
            app.btnClearSelection = uibutton(app.UIFigure, 'push');
            app.btnClearSelection.ButtonPushedFcn = createCallbackFcn(app, @btnClearSelectionButtonPushed, true);
            app.btnClearSelection.Icon = '48px-Filter-clear.png';
            app.btnClearSelection.Tooltip = {'Clear selection'};
            app.btnClearSelection.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogSplitBy(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end